classdef klasifikasi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Label                 matlab.ui.control.Label
        PreviewPanel          matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        BrainTumorClassificationSystemLabel  matlab.ui.control.Label
        ControlResultPanel    matlab.ui.container.Panel
        StatusTextLabel       matlab.ui.control.Label
        StatusLamp            matlab.ui.control.Lamp
        StatusLabel           matlab.ui.control.Label
        ConfidenceEditField   matlab.ui.control.EditField
        ConfidenceLabel       matlab.ui.control.Label
        ClassEditField        matlab.ui.control.EditField
        ClassEditFieldLabel   matlab.ui.control.Label
        ClassificationButton  matlab.ui.control.Button
        LoadImageButton       matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
        UITable               matlab.ui.control.Table
        ContextMenu           matlab.ui.container.ContextMenu
        Menu                  matlab.ui.container.Menu
        Menu2                 matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
 global a;

    [filename, pathname] = uigetfile( ...
        {'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'}, ...
        'Pilih Gambar MRI');

    if isequal(filename,0)
        return;
    end

    fullpath = fullfile(pathname, filename);
    a = imread(fullpath);

    imshow(a, 'Parent', app.UIAxes);
    axis(app.UIAxes, 'image');
    app.StatusLamp.Color = [1 1 0];   % kuning = gambar sudah dipilih / ready
    app.StatusTextLabel.Text = 'Ready';
        end

        % Button pushed function: ClassificationButton
        function ClassificationButtonPushed(app, event)
    try
        global a;

        if isempty(a)
            app.StatusLamp.Color = [1 0 0]; % merah
            uialert(app.UIFigure, 'No image selected.', 'Warning');
            app.StatusTextLabel.Text = 'No image selected';
            return;
        end

        app.StatusLamp.Color = [1 1 0];
        app.StatusTextLabel.Text = 'Processing...';
        drawnow;

        img = imresize(a, [256 256]);

        if size(img,3) == 3
            gray = rgb2gray(img);
        else
            gray = img;
        end

        gray = adapthisteq(gray);

        %% Gabor
        gaborArray = gaborFilterBank(2, 4, 15, 15);
        fiturGabor = gaborFeatures(gray, gaborArray, 32, 32);

        %% DWT
        imgD = im2double(gray);
        waveletName = 'db4';

        [cA1,cH1,cV1,cD1] = dwt2(imgD, waveletName);
        [cA2,cH2,cV2,cD2] = dwt2(cA1, waveletName);

        fiturDWT = [];
        subbands = {cA2, cH2, cV2, cD2, cH1, cV1, cD1};

        for s = 1:length(subbands)
            sb = subbands{s};
            fiturDWT = [fiturDWT mean(sb(:)) std(sb(:)) sum(sb(:).^2)];
        end

        %% Gabung fitur
        fiturGabor = fiturGabor(:)';
        fiturDWT = fiturDWT(:)';
        rowFeatures = [fiturGabor fiturDWT];

        %% Load model
        load('otakLMHN20.mat', 'net')

        %% Klasifikasi
        y = sim(net, rowFeatures');
        y = y(:);

        % Amankan kalau ada output negatif
        y(y < 0) = 0;

        % Normalisasi ke probabilitas
        if sum(y) == 0
            prob = [0; 0; 0];
        else
            prob = (y ./ sum(y)) * 100;
        end

        prob = round(prob, 2);

        % Urutan kelas harus sama dengan training target
        kelas = {'GLIOMA'; 'MENINGIOMA'; 'PITUITARY'};

        [maksProb, idx] = max(prob);
        hasilKelas = kelas{idx};

        % Tampilkan hasil
        app.ClassEditField.Value = hasilKelas;
        app.ConfidenceEditField.Value = sprintf('%.2f%%', maksProb);

        % Tampilkan tabel
        dataTabel = [kelas, num2cell(prob)];
        app.UITable.Data = dataTabel;

        app.StatusLamp.Color = [0 1 0];
        app.StatusTextLabel.Text = 'Finished';

    catch ME
        app.StatusLamp.Color = [1 0 0]; % merah = error
        disp('=== ERROR DETAIL ===')
        disp(ME.message)
        for k = 1:length(ME.stack)
            disp([ME.stack(k).name, ' | line ', num2str(ME.stack(k).line)])
        end
        uialert(app.UIFigure, ME.message, 'Error Klasifikasi');
    end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
    global a;
    a = [];

    cla(app.UIAxes);
    app.ClassEditField.Value = '';
    app.ConfidenceEditField.Value = '';
    app.UITable.Data = {};
    app.StatusLamp.Color = [1 0 0];
    app.StatusTextLabel.Text = 'No image selected';
        end

        % Value changed function: ClassEditField
        function ClassEditFieldValueChanged(app, event)
            value = app.ClassEditField.Value;
            I = app.ClassEditField.Value;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end

        % Value changing function: ClassEditField
        function ClassEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: ConfidenceEditField
        function ConfidenceEditFieldValueChanged(app, event)
            value = app.ConfidenceEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlResultPanel = uipanel(app.UIFigure);
            app.ControlResultPanel.Title = 'Control & Result';
            app.UITable = uitable(app.ControlResultPanel);
            app.UITable.ColumnName = {'Class'; 'Probability(%)'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.ResetButton = uibutton(app.ControlResultPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LoadImageButton = uibutton(app.ControlResultPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ClassificationButton = uibutton(app.ControlResultPanel, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificationButtonPushed, true);
            app.ClassificationButton.Text = 'Classification';
            app.ClassEditFieldLabel = uilabel(app.ControlResultPanel);
            app.ClassEditFieldLabel.Text = 'Class :';
            app.ClassEditField = uieditfield(app.ControlResultPanel, 'text');
            app.ClassEditField.ValueChangedFcn = createCallbackFcn(app, @ClassEditFieldValueChanged, true);
            app.ClassEditField.ValueChangingFcn = createCallbackFcn(app, @ClassEditFieldValueChanging, true);
            app.ConfidenceLabel = uilabel(app.ControlResultPanel);
            app.ConfidenceLabel.Text = 'Confidence :';
            app.ConfidenceEditField = uieditfield(app.ControlResultPanel, 'text');
            app.ConfidenceEditField.ValueChangedFcn = createCallbackFcn(app, @ConfidenceEditFieldValueChanged, true);
            app.StatusLabel = uilabel(app.ControlResultPanel);
            app.StatusLabel.Text = 'Status :';
            app.StatusLamp = uilamp(app.ControlResultPanel);
            app.StatusTextLabel = uilabel(app.ControlResultPanel);
            app.StatusTextLabel.Text = 'Not Ready';
            app.BrainTumorClassificationSystemLabel = uilabel(app.UIFigure);
            app.BrainTumorClassificationSystemLabel.Text = 'Brain Tumor Classification System';
            app.PreviewPanel = uipanel(app.UIFigure);
            app.PreviewPanel.Title = 'Preview';
            app.UIAxes = uiaxes(app.PreviewPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Sistem Pendeteksian Dini dan Klasifikasi Tumor Otak Berdasarkan Citra MRI Koronal';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.StatusLamp.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = klasifikasi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end